classdef Control_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        OpenSerialPortButton          matlab.ui.control.Button
        CloseSerialPortButton         matlab.ui.control.Button
        TrajectoryfileEditFieldLabel  matlab.ui.control.Label
        TrajectoryfileEditField       matlab.ui.control.EditField
        readtrajectoryButton          matlab.ui.control.Button
        downloadtrajectorytorobotButton  matlab.ui.control.Button
        OpenLeftgrapperButton         matlab.ui.control.Button
        CloseLeftgrapperButton        matlab.ui.control.Button
        OpenRightgrapperButton        matlab.ui.control.Button
        CloseRightgrapperButton       matlab.ui.control.Button
        InitPoseButton                matlab.ui.control.Button
        ExecutetrajectoryButton       matlab.ui.control.Button
        AbortAllActionsButton         matlab.ui.control.Button
        SendNotifyMsgButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        traj_idx = 1; % Description
        s;
        send_traj = struct;
        send_on = 0;
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             while 1
%                 if (app.s.Status == 'open' && app.send_on == 0)
%                     
%                 end
%                 pause(0.1);
%             end
        end

        % Button pushed function: OpenSerialPortButton
        function OpenSerialPortButtonPushed(app, event)
            app.s = serial('COM5');
            set(app.s,'BaudRate',115200);
            app.s.ByteOrder = 'littleEndian';
            fopen(app.s);
            app.OpenLeftgrapperButton.Enable = 'on';
            app.OpenRightgrapperButton.Enable = 'on';
            app.CloseLeftgrapperButton.Enable = 'on';
            app.CloseRightgrapperButton.Enable = 'on';
            
            
            app.AbortAllActionsButton.Enable = 'on';
            app.downloadtrajectorytorobotButton.Enable = 'on';
            app.SendNotifyMsgButton.Enable = 'on';
            app.ExecutetrajectoryButton.Enable = 'on';
            app.InitPoseButton.Enable = 'on';
        end

        % Button pushed function: CloseSerialPortButton
        function CloseSerialPortButtonPushed(app, event)
            fclose(app.s);
            app.OpenLeftgrapperButton.Enable = 'off';
            app.OpenRightgrapperButton.Enable = 'off';
            app.CloseLeftgrapperButton.Enable = 'off';
            app.CloseRightgrapperButton.Enable = 'off';
            app.InitPoseButton.Enable = 'off';
            app.downloadtrajectorytorobotButton.Enable = 'off';
            app.ExecutetrajectoryButton.Enable = 'off';
            app.AbortAllActionsButton.Enable = 'off';
            app.SendNotifyMsgButton.Enable = 'off';
        end

        % Button pushed function: OpenRightgrapperButton
        function OpenRightgrapperButtonPushed(app, event)
            SendData.flag = uint8(170);
            SendData.type = uint8(9);    
            SendData.value = int16(0); 
            SendData.position = int32(0);
            SendData.velocity = int32(0);
            SendData.crc = pack_and_cal_crc(SendData);
            send_struct(app.s, SendData);
        end

        % Button pushed function: CloseRightgrapperButton
        function CloseRightgrapperButtonPushed(app, event)
            SendData.flag = uint8(170);
            SendData.type = uint8(9);    
            SendData.value = int16(1); 
            SendData.position = int32(0);
            SendData.velocity = int32(0);
            SendData.crc = pack_and_cal_crc(SendData);
            send_struct(app.s, SendData);
        end

        % Button pushed function: OpenLeftgrapperButton
        function OpenLeftgrapperButtonPushed(app, event)
            SendData.flag = uint8(170);
            SendData.type = uint8(8);    
            SendData.value = int16(0); 
            SendData.position = int32(0);
            SendData.velocity = int32(0);
            SendData.crc = pack_and_cal_crc(SendData);
            send_struct(app.s, SendData);
        end

        % Button pushed function: CloseLeftgrapperButton
        function CloseLeftgrapperButtonPushed(app, event)
            SendData.flag = uint8(170);
            SendData.type = uint8(8);    
            SendData.value = int16(1); 
            SendData.position = int32(0);
            SendData.velocity = int32(0);
            SendData.crc = pack_and_cal_crc(SendData);
            send_struct(app.s, SendData);
        end

        % Button pushed function: downloadtrajectorytorobotButton
        function downloadtrajectorytorobotButtonPushed(app, event)
            app.send_on = 1;                
            send_left_or_right = 1;     % if 1, send left trajectory, if 2, send right trajectory
            app.traj_idx = 1;
            FSM_state = 1;  % this sender is a finite state machine. 
                            % state = 1: wait for the ack of the notification package 
                            % state = 2: send a trajectory package
                            % state = 3: wait for the ack of the previous trajectory package
                            % state = 4: a notification message to start trajectory
            ack_wait_times = 0;
            databuffer_wait = 0;
            MAX_ACK_WAIT_TIMES = 10;   % if ack_wait_times
            ReceiveData = struct;                
            while (app.send_on)
            
                switch FSM_state
                    case 1
                        while (app.s.BytesAvailable == 0)
                            disp('no data in serial buffer')
                            databuffer_wait  = databuffer_wait + 1
                            if (databuffer_wait > 100)
                                app.send_on = 0;
                                break;
                            end
                        end
                        if (app.send_on == 0)
                            break;
                        end
                        % wait for the ack of the notification package 
                        A = fread(app.s,1,'uchar');
                        if (A(1) == uint8(170)) % the head of a frame 
                            B = fread(app.s,13,'uchar');
                            crc = B(12:13);
                            crc16 = crcccitt([A(1);B(1:11)]);
                            my_crc = [uint8(bi2de(bitget(crc16, 1:8)));uint8(bitshift(crc16, -8))];        % pay attention to the order because of little endian
            
                            if (sum(my_crc == crc) == 2)
                                ReceiveData.type = B(1);  
                                if (ReceiveData.type == 40)  %  ACK 
                                    
                                    if (send_left_or_right == 1)
                                        disp('got ACK from MCU, ready to upload left trajectory point 1')
                                    else
                                        
                                        disp('got ACK from MCU, ready to upload right trajectory point 1')
                                    end
                                    FSM_state = 2;
                                    continue;
                                end
                            end
                        end
                        
                        ack_wait_times = ack_wait_times + 1;
                        if (ack_wait_times > MAX_ACK_WAIT_TIMES)
                            disp('no ACK!!!! something is wrong')
                        	break;
                        end
                        
                    case 2
                        app.traj_idx
                        SendData.flag = uint8(170);
                        if (send_left_or_right == 1)
                            SendData.type = uint8(3);    % type = 3 means this message contains a left trajectory point
                            SendData.value = int16(app.traj_idx-1);
                            SendData.position = int32(app.send_traj.new_x_list(2,app.traj_idx)*1000);
                            SendData.velocity = int32(app.send_traj.new_x_list(5,app.traj_idx)*1000);
                        else
                            SendData.type = uint8(4);    % type = 4 means this message contains a right trajectory point
                            SendData.value = int16(app.traj_idx-1);
                            SendData.position = int32(app.send_traj.new_x_list(3,app.traj_idx)*1000);
                            SendData.velocity = int32(app.send_traj.new_x_list(6,app.traj_idx)*1000);
                        end
                        SendData.crc = pack_and_cal_crc(SendData);
                        send_struct(app.s, SendData);
                        
                        FSM_state = 3;
                        ack_wait_times = 0;
                    case 3
                        while (app.s.BytesAvailable == 0)
                            disp('no data in serial buffer')
                        end
                        app.s.BytesAvailable
                        % wait for the ack of the notification package 
                        A = fread(app.s,1,'uchar');
                        if (A(1) == uint8(170)) % the head of a frame 
                            B = fread(app.s,13,'uchar');
                            crc = B(12:13);
                            crc16 = crcccitt([A(1);B(1:11)]);
                            my_crc = [uint8(bi2de(bitget(crc16, 1:8)));uint8(bitshift(crc16, -8))];        % pay attention to the order because of little endian
            
                            if (sum(my_crc == crc) == 2)
                                ReceiveData.type = B(1);   
                                if (ReceiveData.type == 40)   %  ACK 
                                    FSM_state = 2;
                                    if app.traj_idx < size(app.send_traj.new_x_list,2)
                                        app.traj_idx = app.traj_idx + 1;
                                        if (send_left_or_right == 1)
                                            X = sprintf('got ACK from MCU, ready to upload left trajectory point %d', app.traj_idx);
                                            disp(X);
                                        else
                                            X = sprintf('got ACK from MCU, ready to upload right trajectory point %d', app.traj_idx);
                                            disp(X);
                                        end
                                        continue;
                                    else % traj_idx = step+1
                                        if (send_left_or_right == 1)
                                            send_left_or_right = 2;
                                            FSM_state = 2;
                                            ack_wait_times = 0;
                                            app.traj_idx = 1;
                                            continue;
                                        elseif send_left_or_right == 2
                                            FSM_state = 4;
                                            ack_wait_times = 0;
                                            disp('got ACK from MCU, notify finish sending trajectory')
                                            continue;
                                        end
                                    end
                                elseif (ReceiveData.type == 100)
                                    X = sprintf('got ERROR from MCU, resend trajectory point %d', traj_idx);
                                    disp(X);
                                    FSM_state = 2;
                                end
                            end
                        end
                        ack_wait_times = ack_wait_times + 1;
                        if (ack_wait_times > MAX_ACK_WAIT_TIMES)
                            disp('no ACK!!!! resend current data point')
                        	FSM_state = 2;
                        end
                        
                    case 4
                        SendData.flag = uint8(170);
                        SendData.type = uint8(5);    
                        SendData.value = int16(app.traj_idx); 
                        SendData.position = int32(0);
                        SendData.velocity = int32(0);
                        SendData.crc = pack_and_cal_crc(SendData);
                        send_struct(app.s, SendData);
                        disp('notify MCU finish sending trajectory')
                        break;
                        
                end
                
                
                pause(0.05);
            end            
            app.send_on = 0;
        end

        % Button pushed function: InitPoseButton
        function InitPoseButtonPushed(app, event)
            SendData.flag = uint8(170);
            SendData.type = uint8(7);    
            SendData.value = int16(app.traj_idx); 
            SendData.position = int32(0);
            SendData.velocity = int32(0);
            SendData.crc = pack_and_cal_crc(SendData);
            send_struct(app.s, SendData);
        end

        % Button pushed function: ExecutetrajectoryButton
        function ExecutetrajectoryButtonPushed(app, event)
            SendData.flag = uint8(170);
            SendData.type = uint8(6);    
            SendData.value = int16(app.traj_idx); 
            SendData.position = int32(0);
            SendData.velocity = int32(0);
            SendData.crc = pack_and_cal_crc(SendData);
            send_struct(app.s, SendData);
        end

        % Button pushed function: readtrajectoryButton
        function readtrajectoryButtonPushed(app, event)
            app.send_traj = load(app.TrajectoryfileEditField.Value);
        end

        % Button pushed function: AbortAllActionsButton
        function AbortAllActionsButtonPushed(app, event)
            SendData.flag = uint8(170);
            SendData.type = uint8(0);    
            SendData.value = int16(0); 
            SendData.position = int32(0);
            SendData.velocity = int32(0);
            SendData.crc = pack_and_cal_crc(SendData);
            send_struct(app.s, SendData);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fclose(app.s);
            delete(app)
            
        end

        % Button pushed function: SendNotifyMsgButton
        function SendNotifyMsgButtonPushed(app, event)
            SendData = struct;
            % first config SendData to be a notification message
            SendData.flag = uint8(170);
            SendData.type = uint8(2);    % type = 2 means this message notify monkey to prepare to receive trajectory
            SendData.value = int16(0);
            SendData.position = int32(0);
            SendData.velocity = int32(0);
            SendData.crc = pack_and_cal_crc(SendData);
            
            send_struct(app.s, SendData);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OpenSerialPortButton = uibutton(app.UIFigure, 'push');
            app.OpenSerialPortButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSerialPortButtonPushed, true);
            app.OpenSerialPortButton.Text = 'Open Serial Port';
            app.CloseSerialPortButton = uibutton(app.UIFigure, 'push');
            app.CloseSerialPortButton.ButtonPushedFcn = createCallbackFcn(app, @CloseSerialPortButtonPushed, true);
            app.CloseSerialPortButton.Text = 'Close Serial Port';
            app.TrajectoryfileEditFieldLabel = uilabel(app.UIFigure);
            app.TrajectoryfileEditFieldLabel.Text = 'Trajectory file';
            app.TrajectoryfileEditField = uieditfield(app.UIFigure, 'text');
            app.TrajectoryfileEditField.Value = 'test045_to_045.mat';
            app.readtrajectoryButton = uibutton(app.UIFigure, 'push');
            app.readtrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @readtrajectoryButtonPushed, true);
            app.readtrajectoryButton.Text = 'read trajectory';
            app.downloadtrajectorytorobotButton = uibutton(app.UIFigure, 'push');
            app.downloadtrajectorytorobotButton.ButtonPushedFcn = createCallbackFcn(app, @downloadtrajectorytorobotButtonPushed, true);
            app.downloadtrajectorytorobotButton.Enable = 'off';
            app.downloadtrajectorytorobotButton.Text = 'download trajectory to robot';
            app.OpenLeftgrapperButton = uibutton(app.UIFigure, 'push');
            app.OpenLeftgrapperButton.ButtonPushedFcn = createCallbackFcn(app, @OpenLeftgrapperButtonPushed, true);
            app.OpenLeftgrapperButton.Enable = 'off';
            app.OpenLeftgrapperButton.Text = 'Open Left grapper';
            app.CloseLeftgrapperButton = uibutton(app.UIFigure, 'push');
            app.CloseLeftgrapperButton.ButtonPushedFcn = createCallbackFcn(app, @CloseLeftgrapperButtonPushed, true);
            app.CloseLeftgrapperButton.Enable = 'off';
            app.CloseLeftgrapperButton.Text = 'Close Left grapper';
            app.OpenRightgrapperButton = uibutton(app.UIFigure, 'push');
            app.OpenRightgrapperButton.ButtonPushedFcn = createCallbackFcn(app, @OpenRightgrapperButtonPushed, true);
            app.OpenRightgrapperButton.Enable = 'off';
            app.OpenRightgrapperButton.Text = 'Open Right grapper';
            app.CloseRightgrapperButton = uibutton(app.UIFigure, 'push');
            app.CloseRightgrapperButton.ButtonPushedFcn = createCallbackFcn(app, @CloseRightgrapperButtonPushed, true);
            app.CloseRightgrapperButton.Enable = 'off';
            app.CloseRightgrapperButton.Text = 'Close Right grapper';
            app.InitPoseButton = uibutton(app.UIFigure, 'push');
            app.InitPoseButton.ButtonPushedFcn = createCallbackFcn(app, @InitPoseButtonPushed, true);
            app.InitPoseButton.Enable = 'off';
            app.InitPoseButton.Text = 'Init Pose';
            app.ExecutetrajectoryButton = uibutton(app.UIFigure, 'push');
            app.ExecutetrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutetrajectoryButtonPushed, true);
            app.ExecutetrajectoryButton.Enable = 'off';
            app.ExecutetrajectoryButton.Text = 'Execute trajectory';
            app.AbortAllActionsButton = uibutton(app.UIFigure, 'push');
            app.AbortAllActionsButton.ButtonPushedFcn = createCallbackFcn(app, @AbortAllActionsButtonPushed, true);
            app.AbortAllActionsButton.Enable = 'off';
            app.AbortAllActionsButton.Text = 'Abort All Actions';
            app.SendNotifyMsgButton = uibutton(app.UIFigure, 'push');
            app.SendNotifyMsgButton.ButtonPushedFcn = createCallbackFcn(app, @SendNotifyMsgButtonPushed, true);
            app.SendNotifyMsgButton.Enable = 'off';
            app.SendNotifyMsgButton.Text = 'Send Notify Msg';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Control_APP

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end